classdef ElectroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        ElectroAppTringulodeTresCargasLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        PosicionP                     matlab.ui.control.NumericEditField
        amLabel_2                     matlab.ui.control.Label
        Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel  matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        amLabel                       matlab.ui.control.Label
        CalculartrabajoButton         matlab.ui.control.Button
        CalcularcampoelectricoButton  matlab.ui.control.Button
        q3Field                       matlab.ui.control.NumericEditField
        q3CLabel                      matlab.ui.control.Label
        q2Field                       matlab.ui.control.NumericEditField
        q2CLabel                      matlab.ui.control.Label
        q1Field                       matlab.ui.control.NumericEditField
        q1CLabel                      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Panel                         matlab.ui.container.Panel
        ResultadoCampo                matlab.ui.control.Label
        ResultadosETextArea           matlab.ui.control.TextArea
        ResultadosdelcampoelctricoLabel  matlab.ui.control.Label
        ResultadoLabel                matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularcampoelectricoButton
        function CalcularcampoelectricoButtonPushed(app, event)
            
            % ----- Variables de entrada-----
            % se abtre los datos
            % Obtener valores (en µC, convertir a C)
            q1 = app.q1Field.Value * 1e-6;
            q2 = app.q2Field.Value * 1e-6;
            q3 = app.q3Field.Value * 1e-6;
            a  = app.aField.Value; % metros  
            p = app.PosicionP
            ke = 9e9;  % Constante de Coulomb [N·m^2/C^2]
            % Altura del triangulo equilatero
            h = (sqrt(3)/2)*a;
            
            % se crea la valiabes que indica la posicion de cada carga
            y = p;
            Px = a/2; 
            Py = y;
            
            % Distancias desde P a cada carga
            %allar vectores de de P a q1
            r1 = sqrt((Px-0)^2 + (Py-0)^2);   % P a q1

            %allar vectores de de P a q2
            r2 = sqrt((Px-a)^2 + (Py-0)^2);   % P a q2

            %allar vectores de de P a q3
            r3 = abs(Py-h);                   % P a q3 (vertical)
            

            % se alla Vectores unitarios
            % de cada carga respecto P
            % unitario de P1 P
            u1 = [(Px-0) (Py-0)]/r1;
            % unitario de P2 P
            u2 = [(Px-a) (Py-0)]/r2;
            % unitario de P3 P
            u3 = [0 (Py-h)]/r3;


        end

        % Button pushed function: CalculartrabajoButton
        function CalculartrabajoButtonPushed(app, event)
            q1 = app.q1Field.Value * 1e-6; % Convertir la carga 1 de µC a C.
            q2 = app.q2Field.Value * 1e-6; % Convertir la carga 2 de µC a C.
            q3 = app.q3Field.Value * 1e-6; % Convertir la carga 3 de µC a C.
            a  = app.aField.Value; % Longiutd del lado del triángulo en metros.

            % Llamar al script (función) del trabajo.
            W = trabajoCargas(q1, q2, q3, a);
        
            % Mostrar resultado 
            app.ResultadoLabel.Text = ['Trabajo: ' num2str(W) ' J'];

            % Gráfica
            k = 9e9; % Constante de Coulomb.
            a_values = linspace(0.05, 1, 100); % de 5 cm a 1 m
            W_values = k * (q1*q2 + q1*q3 + q2*q3) ./ a_values; % calcula el trabajo total del sistema para cada una de esas distancias.
        
            % Dibujar en el UIAxes
            plot(app.UIAxes, a_values, W_values, 'r-', 'LineWidth', 2); % Dibujar curva.
            xlabel(app.UIAxes, 'Distancia a (m)'); % Etiqueta eje x.
            ylabel(app.UIAxes, 'Trabajo W (J)'); % Etiqueta eje y.
            title(app.UIAxes, 'Trabajo en función de la distancia a'); % Título de la gráfica.
            grid(app.UIAxes, 'on'); % Activa la cuadrícula de fondo.


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Text = 'Trabajo (W): 0,00 J';
            app.ResultadosdelcampoelctricoLabel = uilabel(app.Panel);
            app.ResultadosdelcampoelctricoLabel.Text = 'Resultados del campo eléctrico (∣E∣):';
            app.ResultadosETextArea = uitextarea(app.Panel);
            app.ResultadoCampo = uilabel(app.Panel);
            app.ResultadoCampo.Text = 'Campoo (E): 0,00 N/C';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagen.png');
            app.q1CLabel = uilabel(app.Panel_2);
            app.q1CLabel.Text = 'q1 (µC):';
            app.q1Field = uieditfield(app.Panel_2, 'numeric');
            app.q2CLabel = uilabel(app.Panel_2);
            app.q2CLabel.Text = 'q2 (µC) :';
            app.q2Field = uieditfield(app.Panel_2, 'numeric');
            app.q3CLabel = uilabel(app.Panel_2);
            app.q3CLabel.Text = 'q3 (µC): ';
            app.q3Field = uieditfield(app.Panel_2, 'numeric');
            app.CalcularcampoelectricoButton = uibutton(app.Panel_2, 'push');
            app.CalcularcampoelectricoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularcampoelectricoButtonPushed, true);
            app.CalcularcampoelectricoButton.Text = 'Calcular campo electrico';
            app.CalculartrabajoButton = uibutton(app.Panel_2, 'push');
            app.CalculartrabajoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculartrabajoButtonPushed, true);
            app.CalculartrabajoButton.Text = 'Calcular trabajo';
            app.amLabel = uilabel(app.Panel_2);
            app.amLabel.Text = 'a (m): ';
            app.aField = uieditfield(app.Panel_2, 'numeric');
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel = uilabel(app.Panel_2);
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel.Text = '"Tres cargas puntuales se ubican en los vértices de un triángulo equilátero ( q1,q2: positivas y q3 negativa). La aplicación calcula el campo eléctrico a lo largo de la altura y el trabajo necesario para ensamblar el sistema, a partir de los valores de las cargas y la distancia entre ellas."';
            app.amLabel_2 = uilabel(app.Panel_2);
            app.amLabel_2.Text = 'P (m): ';
            app.PosicionP = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.ElectroAppTringulodeTresCargasLabel = uilabel(app.Panel_3);
            app.ElectroAppTringulodeTresCargasLabel.Text = 'ElectroApp: Triángulo de Tres Cargas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end